classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        NouveauMenu      matlab.ui.container.Menu
        Grille3x3Menu    matlab.ui.container.Menu
        Grille2x2Menu    matlab.ui.container.Menu
        Grille4x4Menu    matlab.ui.container.Menu
        SauvegarderMenu  matlab.ui.container.Menu
        OuvrirMenu       matlab.ui.container.Menu
        RsoudreMenu      matlab.ui.container.Menu
        ParamtresMenu    matlab.ui.container.Menu
        GridLayout       matlab.ui.container.GridLayout
        LeftPanel        matlab.ui.container.Panel
        RightPanel       matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function generateSudoku(app, event)
            
        end

        % Menu selected function: Grille3x3Menu
        function generateGrid3(app, event)
        CornerX = 25;
        CornerY = 410;
        dx = 35;
        dy = 35;
        for ii = 1:9
            for jj = 1:9
                xPos = CornerX + (jj-1)*dx;
                yPos = CornerY - (ii-1)*dy; 
                if (ii <= 3 && jj <= 3) || (ii >= 7 && jj <=3) || (ii >= 7 && jj >=7) || ((ii > 3 && ii < 7) && (jj > 3 && jj < 7)) || (jj >= 7 && ii <=3)
                    sudokugui.X(ii,jj) = uicontrol('unit','pixels',...
                                               'background','w',...
                                               'Style','edit',...
                                               'visible','on',...
                                               'position',[xPos,yPos,dx dy],...
                                               'fontsize',14',...
                                               'fontweight','normal',...
                                               'enable','on',...
                                               'KeyPressFcn',{@cbEntry,app.LeftPanel,ii,jj});
                else
                    sudokugui.X(ii,jj) = uicontrol('unit','pixels',...
                                               'background','#9ea5b0',...
                                               'Style','edit',...
                                               'visible','on',...
                                               'position',[xPos,yPos,dx dy],...
                                               'fontsize',14',...
                                               'fontweight','normal',...
                                               'enable','on',...
                                               'KeyPressFcn',{@cbEntry,app.LeftPanel,ii,jj});
                end
            end
        end
        end

        % Menu selected function: Grille2x2Menu
        function generateGrid2(app, event)
            try
                delete(app.LeftPanel.Children);
            catch
                warning("problemo");
            end
            data = zeros(4);
            h1 = uitable(app.LeftPanel,'Data',data,'FontSize',16,'ColumnWidth',num2cell(repmat(30,1,4)),'columne',false(1,4));
            set(h1,'units','norm','position',[.30 .30 .22 .22]);
        end

        % Menu selected function: Grille4x4Menu
        function generateGrid4(app, event)
            try
                delete(app.LeftPanel.Children);
            catch
                warning("problemo");
            end
            data = zeros(12);
            h1 = uitable(app.LeftPanel,'Data',data,'FontSize',16,'ColumnWidth',num2cell(repmat(30,1,12)),'columne',false(1,12));
            set(h1,'units','norm','position',[.15 .15 .53 .56]);
        end

        % Menu selected function: OuvrirMenu
        function openImage(app, event)
            [file, path] = uigetfile({'*.jpg';'*.bmp';'*.gif';'*.tiff'}, 'Select file');
            i = imread(file);
            imshow(i,app.LeftPanel);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {637, 637};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {763, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.NouveauMenu = uimenu(app.UIFigure);
            app.NouveauMenu.Text = 'Nouveau';
            app.Grille3x3Menu = uimenu(app.NouveauMenu);
            app.Grille3x3Menu.MenuSelectedFcn = createCallbackFcn(app, @generateGrid3, true);
            app.Grille3x3Menu.Text = 'Grille 3x3';
            app.Grille2x2Menu = uimenu(app.NouveauMenu);
            app.Grille2x2Menu.MenuSelectedFcn = createCallbackFcn(app, @generateGrid2, true);
            app.Grille2x2Menu.Text = 'Grille 2x2';
            app.Grille4x4Menu = uimenu(app.NouveauMenu);
            app.Grille4x4Menu.MenuSelectedFcn = createCallbackFcn(app, @generateGrid4, true);
            app.Grille4x4Menu.Text = 'Grille 4x4';
            app.SauvegarderMenu = uimenu(app.UIFigure);
            app.SauvegarderMenu.Text = 'Sauvegarder';
            app.OuvrirMenu = uimenu(app.UIFigure);
            app.OuvrirMenu.MenuSelectedFcn = createCallbackFcn(app, @openImage, true);
            app.OuvrirMenu.Text = 'Ouvrir';
            app.RsoudreMenu = uimenu(app.UIFigure);
            app.RsoudreMenu.Text = 'Résoudre';
            app.ParamtresMenu = uimenu(app.UIFigure);
            app.ParamtresMenu.Text = 'Paramètres';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end