classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        Image                   matlab.ui.control.Image
        CenterPanel             matlab.ui.container.Panel
        RightPanel              matlab.ui.container.Panel
        VosgrillesListBoxLabel  matlab.ui.control.Label
        VosgrillesListBox       matlab.ui.control.ListBox
        GrilleMenu              matlab.ui.container.Menu
        EnregistrerMenu         matlab.ui.container.Menu
        DeleteMenu              matlab.ui.container.Menu
        ClearMenu               matlab.ui.container.Menu
        RsoudreMenu             matlab.ui.container.Menu
        OuvrirMenu              matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        myGrid % Grille de sudoku
        gridName % Nom de la grille de sudoku
    end
    
    methods (Access = private)
        
        function refreshDir(app)
            bmpfiles = dir('./existingSudokus/*.csv');
            listString = cell(1, numel(bmpfiles));
            [listString{:}] = bmpfiles.name;
            set(app.VosgrillesListBox,'Items',listString);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function guiInit(app)
            firstX = 35; dx = 35;
            firstY = 450; dy = 35;
            for ii = 1:9
                for jj = 1:9
                    xPos = firstX + (jj-1)*dx;
                    yPos = firstY - (ii-1)*dy; 
                        if (ii <= 3 && jj <= 3) || (ii >= 7 && jj <=3) || (ii >= 7 && jj >=7) || ((ii > 3 && ii < 7) && (jj > 3 && jj < 7)) || (jj >= 7 && ii <=3)
                            app.myGrid(ii,jj) = uieditfield(app.CenterPanel,"numeric", ...
                                            "Limits",[0 9], ...
                                            "Position",[xPos yPos dx dy], ...
                                            "HorizontalAlignment","center", ...
                                            "BackgroundColor","white");
                        else
                            app.myGrid(ii,jj) = uieditfield(app.CenterPanel,"numeric", ...
                                            "Limits",[0 9], ...
                                            "Position",[xPos yPos dx dy], ...
                                            "HorizontalAlignment","center", ...
                                            "BackgroundColor","#9ea5b0");
                        end
                end
            end   
            app.gridName = uieditfield(app.CenterPanel,"Position",[100 125 200 30],"Value","mySudoku","HorizontalAlignment","center");
            refreshDir(app);
           
        end

        % Menu selected function: ClearMenu
        function clearGrid(app, event)
            set(app.myGrid,'Value',0);
        end

        % Menu selected function: EnregistrerMenu
        function saveGrid(app, event)
            filename = "./existingSudokus/" + get(app.gridName,'value') + ".csv";
            A = zeros(9);
            for i = 1:9
                for j =1:9
                    A(i,j) = get(app.myGrid(i,j),"value");
                end
            end            
            writematrix(A,filename);
            refreshDir(app);
        end

        % Menu selected function: DeleteMenu
        function deleteGrid(app, event)
            filename = "./existingSudokus/" + get(app.VosgrillesListBox,'value');
            if(filename ~= "")
                delete(filename);
            end
            refreshDir(app);
        end

        % Value changed function: VosgrillesListBox
        function VosgrillesListBoxValueChanged(app, event)
            value = app.VosgrillesListBox.Value;
            filename = "./existingSudokus/" + value;
            A = readmatrix(filename);
            for i = 1:9
                for j =1:9
                    set(app.myGrid(i,j),"value",A(i,j));
                end
            end 
        end

        % Menu selected function: RsoudreMenu
        function Solve(app, event)
            %% Handle any type of conflicts 
            A = zeros(9);
            for i = 1:9
                for j =1:9
                    A(i,j) = get(app.myGrid(i,j),"value");
                end
            end  
    
            for ii = 1:9
                for jj = 1:9
                    if(A(ii,jj)~=0)
                        if (ConflictTest(A,ii,jj) == 0)
                            set(sudokugui.status,'string','CONFLICT')
                            return
                        end
                    end
                end
            end
            
            %% Solve my Sudoku
            A_sol = SudokuBacktracker(A);
    
            %% Prompt the user that Sudoku is solved
            for i = 1:9
                for j =1:9
                    set(app.myGrid(i,j),"value",A_sol(i,j));
                end
            end 
        end

        % Menu selected function: OuvrirMenu
        function loadImage(app, event)
            [file, path] = uigetfile(["*.jpg";"*.png"]);
            app.Image.ImageSource = strcat(path,file);
            I = imread(app.Image.ImageSource);
            A = SudokuReader(I);
            for i = 1:9
                for j =1:9
                    set(app.myGrid(i,j),"value",A(i,j));
                end
            end 
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {586, 586, 586};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {586, 586};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {217, '1x', 250};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.LeftPanel);
            app.CenterPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.VosgrillesListBoxLabel = uilabel(app.RightPanel);
            app.VosgrillesListBoxLabel.Text = {'Vos grilles'; ''};
            app.VosgrillesListBox = uilistbox(app.RightPanel);
            app.VosgrillesListBox.Items = {};
            app.VosgrillesListBox.ValueChangedFcn = createCallbackFcn(app, @VosgrillesListBoxValueChanged, true);
            app.VosgrillesListBox.Value = {};
            app.GrilleMenu = uimenu(app.UIFigure);
            app.GrilleMenu.Text = 'Grille';
            app.EnregistrerMenu = uimenu(app.GrilleMenu);
            app.EnregistrerMenu.MenuSelectedFcn = createCallbackFcn(app, @saveGrid, true);
            app.EnregistrerMenu.Text = 'Enregistrer';
            app.DeleteMenu = uimenu(app.GrilleMenu);
            app.DeleteMenu.MenuSelectedFcn = createCallbackFcn(app, @deleteGrid, true);
            app.DeleteMenu.Text = 'Delete';
            app.ClearMenu = uimenu(app.GrilleMenu);
            app.ClearMenu.MenuSelectedFcn = createCallbackFcn(app, @clearGrid, true);
            app.ClearMenu.Text = 'Clear';
            app.RsoudreMenu = uimenu(app.UIFigure);
            app.RsoudreMenu.MenuSelectedFcn = createCallbackFcn(app, @Solve, true);
            app.RsoudreMenu.Text = 'Résoudre';
            app.OuvrirMenu = uimenu(app.UIFigure);
            app.OuvrirMenu.MenuSelectedFcn = createCallbackFcn(app, @loadImage, true);
            app.OuvrirMenu.Text = 'Ouvrir';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @guiInit)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end